classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        StatusLabel          matlab.ui.control.Label
        RunSimulationButton  matlab.ui.control.Button
        TEditField           matlab.ui.control.NumericEditField
        TTemparatureLabel    matlab.ui.control.Label
        kEditField           matlab.ui.control.NumericEditField
        kLeakageRateLabel    matlab.ui.control.Label
        P0EditField          matlab.ui.control.NumericEditField
        P0initialPressureEditFieldLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            % Get user inputs from fields
P0 = app.P0EditField.Value;  % Initial Pressure
k  = app.kEditField.Value;   % Leakage Rate
T  = app.TEditField.Value;   % Temperature
P_flat = 20;                  % Flat tyre threshold
dt = 0.5;                      % Time step
time = 0:dt:50;               % Simulation time in minutes

% Simulate exponential pressure decay
P = P0 * exp(-k*time);

% Temperature correction (optional)
T_ref = 293.15; % reference temp
P_norm = P .* (T_ref ./ T); 

% Derivative
dPdt = diff(P_norm)./diff(time);

% Decide status
P_final = P_norm(end);
if P_final > 28 && mean(dPdt) > -0.05
    status = 'Healthy';
elseif P_final > P_flat && mean(dPdt) <= -0.05
    status = 'Puncture Suspected';
else
    status = 'Flat Tyre';
end

% Display status in Label
app.StatusLabel.Text = ['Status: ', status];

% Plot in Axes
plot(app.UIAxes, time, P_norm, 'b-', 'LineWidth', 2); hold(app.UIAxes,'on');
yline(app.UIAxes, P_flat, 'r--', 'LineWidth', 2);
xlabel(app.UIAxes, 'Time (minutes)');
ylabel(app.UIAxes, 'Pressure');
title(app.UIAxes, ['Tyre Pressure vs Time -> ', status]);
grid(app.UIAxes, 'on');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Puncture detection')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Pressure')
            zlabel(app.UIAxes, 'Z')
            app.P0initialPressureEditFieldLabel = uilabel(app.UIFigure);
            app.P0initialPressureEditFieldLabel.Text = '.P0(initial Pressure';
            app.P0EditField = uieditfield(app.UIFigure, 'numeric');
            app.kLeakageRateLabel = uilabel(app.UIFigure);
            app.kLeakageRateLabel.Text = '.k (Leakage Rate)';
            app.kEditField = uieditfield(app.UIFigure, 'numeric');
            app.TTemparatureLabel = uilabel(app.UIFigure);
            app.TTemparatureLabel.Text = 'T (Temparature)';
            app.TEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Healthy / Puncture / Flat';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end